classdef simple_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SimpleCalculatorLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        CEditField             matlab.ui.control.NumericEditField
        CEditFieldLabel        matlab.ui.control.Label
        BEditField             matlab.ui.control.NumericEditField
        BEditFieldLabel        matlab.ui.control.Label
        AEditField             matlab.ui.control.NumericEditField
        AEditFieldLabel        matlab.ui.control.Label
        SubButton              matlab.ui.control.Button
        MulButton              matlab.ui.control.Button
        AddButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            A=app.AEditField.Value;
            B=app.BEditField.Value'
            C=A+B;
            app.CEditField.Value=C;
        end

        % Button pushed function: MulButton
        function MulButtonPushed(app, event)
             A=app.AEditField.Value;
            B=app.BEditField.Value'
            C=A*B;
            app.CEditField.Value=C;
        end

        % Button pushed function: SubButton
        function SubButtonPushed(app, event)
             A=app.AEditField.Value;
            B=app.BEditField.Value'
            C=A-B;
            app.CEditField.Value=C;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = {'Add'; ''};
            app.MulButton = uibutton(app.UIFigure, 'push');
            app.MulButton.ButtonPushedFcn = createCallbackFcn(app, @MulButtonPushed, true);
            app.MulButton.Text = {'Mul'; ''};
            app.SubButton = uibutton(app.UIFigure, 'push');
            app.SubButton.ButtonPushedFcn = createCallbackFcn(app, @SubButtonPushed, true);
            app.SubButton.Text = {'Sub'; ''};
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'CALC.png';
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simple_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end